classdef BookKeeperProject < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        BusinessPanel            matlab.ui.container.Panel
        ViewBalanceSheetButton   matlab.ui.control.Button
        ViewPLButton             matlab.ui.control.Button
        TransactionPanel         matlab.ui.container.Panel
        PayEmployeeButton        matlab.ui.control.Button
        CreatePOButton           matlab.ui.control.Button
        ViewPOHistoryButton      matlab.ui.control.Button
        CreateInvoiceButton      matlab.ui.control.Button
        ViewInventoryButton      matlab.ui.control.Button
        ViewPayrollEventsButton  matlab.ui.control.Button
        StaffPanel               matlab.ui.container.Panel
        AddVendorsButton         matlab.ui.control.Button
        AddCustomersButton       matlab.ui.control.Button
        ViewCustomersButton      matlab.ui.control.Button
        ViewVendorsButton        matlab.ui.control.Button
        AddEmployeesButton       matlab.ui.control.Button
        ViewEmployeesButton      matlab.ui.control.Button
    end

    
    properties (Access = private)
        employeeWindow
        addEmployeeWindow
        customerWindow
        addCustomerWindow
        vendorWindow
        addVendorWindow
        payrollEventsWindow
        payEmployeeWindow
        inventoryWindow
        createPOWindow
        pohistoryWindow
        createInvoiceWindow
        incomeWindow
        balanceSheetWindow

        colors
        panelList
        addButtonList
        viewButtonList
    end
    
    methods (Access = private)
        
        function loadUI(app)
     
            for button = app.addButtonList             
                button.BackgroundColor = 'w';
                button.FontColor = app.colors("viewButtonColor");
                button.FontWeight = 'bold';
                button.FontSize = 14;
            end

            for button = app.viewButtonList
                button.BackgroundColor = app.colors("viewButtonColor");
                button.FontColor = 'w';
                button.FontWeight = 'bold';
                button.FontSize = 14;
            end

            for panel = app.panelList
                panel.BackgroundColor = app.colors("panelColor");
                panel.FontSize = 18;
                panel.ForegroundColor = app.colors("viewButtonColor");
                panel.FontWeight = 'bold';
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.colors = containers.Map({'panelColor', 'viewButtonColor'}, {[0.9,0.92,0.93],[0.1,0.4,0.61]}, 'UniformValues',false);
            app.panelList = [app.StaffPanel, app.TransactionPanel, app.BusinessPanel];
            app.addButtonList = [app.AddCustomersButton, app.AddEmployeesButton, app.AddVendorsButton, app.CreateInvoiceButton, app.CreatePOButton, app.PayEmployeeButton];
            app.viewButtonList = [app.ViewCustomersButton, app.ViewEmployeesButton, app.ViewVendorsButton, app.ViewPayrollEventsButton, app.ViewPOHistoryButton, app.ViewInventoryButton, app.ViewPLButton, app.ViewBalanceSheetButton];
            app.loadUI();
        end

        % Button pushed function: ViewEmployeesButton
        function ViewEmployeesButtonPushed(app, event)
            app.ViewEmployeesButton.Enable = 'off';
            
            % Open the options dialog and pass inputs
            app.employeeWindow = employeeWIndow(app, app.colors);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
            delete(app.employeeWindow)
            delete(app.addEmployeeWindow)
            delete(app.customerWindow)
            delete(app.AddCustomersWindow)
        end

        % Button pushed function: AddEmployeesButton
        function AddEmployeesButtonPushed(app, event)
            app.AddEmployeesButton.Enable = 'off';
            
            % Open the options dialog and pass inputs
            app.addEmployeeWindow = addEmployeeWindow(app, false);
        end

        % Button pushed function: ViewCustomersButton
        function ViewCustomersButtonPushed(app, event)
            app.ViewCustomersButton.Enable = 'off';
            
            % Open the options dialog and pass inputs
            app.customerWindow = customerWindow(app, app.colors);
        end

        % Button pushed function: AddCustomersButton
        function AddCustomersButtonPushed(app, event)
            app.AddCustomersButton.Enable = 'off';
            
            % Open the options dialog and pass inputs
            app.addCustomerWindow = addCustomerWindow(app, false);
        end

        % Button pushed function: ViewVendorsButton
        function ViewVendorsButtonPushed(app, event)
            app.ViewVendorsButton.Enable = 'off';
            
            % Open the options dialog and pass inputs
            app.vendorWindow = vendorWindow(app, app.colors);
        end

        % Button pushed function: AddVendorsButton
        function AddVendorsButtonPushed(app, event)
            app.AddVendorsButton.Enable = 'off';
            
            % Open the options dialog and pass inputs
            app.addVendorWindow = addVendorWindow(app, false);
        end

        % Button pushed function: ViewPayrollEventsButton
        function ViewPayrollEventsButtonPushed(app, event)
            app.ViewPayrollEventsButton.Enable = 'off';
            
            % Open the options dialog and pass inputs
            app.payrollEventsWindow = payrollEventWindow(app, app.colors);
        end

        % Button pushed function: PayEmployeeButton
        function PayEmployeeButtonPushed(app, event)
            app.PayEmployeeButton.Enable = 'off';
            
            % Open the options dialog and pass inputs
            app.payEmployeeWindow = payEmployeeWindow(app, false);
        end

        % Button pushed function: ViewInventoryButton
        function ViewInventoryButtonPushed(app, event)
            app.ViewInventoryButton.Enable = 'off';
            
            % Open the options dialog and pass inputs
            app.inventoryWindow = inventoryWindow(app, app.colors);
        end

        % Button pushed function: CreatePOButton
        function CreatePOButtonPushed(app, event)
            app.CreatePOButton.Enable = 'off';
            
            % Open the options dialog and pass inputs
            app.createPOWindow = createPoWindow(app, false);
        end

        % Button pushed function: ViewPOHistoryButton
        function ViewPOHistoryButtonPushed(app, event)
            app.ViewPOHistoryButton.Enable = "off";
            app.pohistoryWindow = poHistoryWindow(app, app.colors);
        end

        % Button pushed function: CreateInvoiceButton
        function CreateInvoiceButtonPushed(app, event)
            app.CreateInvoiceButton.Enable = "off";
            app.createInvoiceWindow = addInvoiceWindow(app, app.colors);
        end

        % Button pushed function: ViewPLButton
        function ViewPLButtonPushed(app, event)
            app.ViewPLButton.Enable = "off";
            app.incomeWindow = incomeWindow(app, app.colors);
        end

        % Button pushed function: ViewBalanceSheetButton
        function ViewBalanceSheetButtonPushed(app, event)
            app.ViewBalanceSheetButton.Enable = "off";
            app.balanceSheetWindow = balanceSheetWindow(app, app.colors);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.StaffPanel = uipanel(app.UIFigure);
            app.StaffPanel.Title = 'Staff';
            app.ViewEmployeesButton = uibutton(app.StaffPanel, 'push');
            app.ViewEmployeesButton.ButtonPushedFcn = createCallbackFcn(app, @ViewEmployeesButtonPushed, true);
            app.ViewEmployeesButton.Text = 'View Employees';
            app.AddEmployeesButton = uibutton(app.StaffPanel, 'push');
            app.AddEmployeesButton.ButtonPushedFcn = createCallbackFcn(app, @AddEmployeesButtonPushed, true);
            app.AddEmployeesButton.Text = 'Add Employees';
            app.ViewVendorsButton = uibutton(app.StaffPanel, 'push');
            app.ViewVendorsButton.ButtonPushedFcn = createCallbackFcn(app, @ViewVendorsButtonPushed, true);
            app.ViewVendorsButton.Text = 'View Vendors';
            app.ViewCustomersButton = uibutton(app.StaffPanel, 'push');
            app.ViewCustomersButton.ButtonPushedFcn = createCallbackFcn(app, @ViewCustomersButtonPushed, true);
            app.ViewCustomersButton.Text = 'View Customers';
            app.AddCustomersButton = uibutton(app.StaffPanel, 'push');
            app.AddCustomersButton.ButtonPushedFcn = createCallbackFcn(app, @AddCustomersButtonPushed, true);
            app.AddCustomersButton.Text = 'Add Customers';
            app.AddVendorsButton = uibutton(app.StaffPanel, 'push');
            app.AddVendorsButton.ButtonPushedFcn = createCallbackFcn(app, @AddVendorsButtonPushed, true);
            app.AddVendorsButton.Text = 'Add Vendors';
            app.TransactionPanel = uipanel(app.UIFigure);
            app.TransactionPanel.Title = 'Transaction';
            app.ViewPayrollEventsButton = uibutton(app.TransactionPanel, 'push');
            app.ViewPayrollEventsButton.ButtonPushedFcn = createCallbackFcn(app, @ViewPayrollEventsButtonPushed, true);
            app.ViewPayrollEventsButton.Text = 'View Payroll Events';
            app.ViewInventoryButton = uibutton(app.TransactionPanel, 'push');
            app.ViewInventoryButton.ButtonPushedFcn = createCallbackFcn(app, @ViewInventoryButtonPushed, true);
            app.ViewInventoryButton.Text = 'View Inventory';
            app.CreateInvoiceButton = uibutton(app.TransactionPanel, 'push');
            app.CreateInvoiceButton.ButtonPushedFcn = createCallbackFcn(app, @CreateInvoiceButtonPushed, true);
            app.CreateInvoiceButton.Text = 'Create Invoice';
            app.ViewPOHistoryButton = uibutton(app.TransactionPanel, 'push');
            app.ViewPOHistoryButton.ButtonPushedFcn = createCallbackFcn(app, @ViewPOHistoryButtonPushed, true);
            app.ViewPOHistoryButton.Text = 'View PO History';
            app.CreatePOButton = uibutton(app.TransactionPanel, 'push');
            app.CreatePOButton.ButtonPushedFcn = createCallbackFcn(app, @CreatePOButtonPushed, true);
            app.CreatePOButton.Text = 'Create PO';
            app.PayEmployeeButton = uibutton(app.TransactionPanel, 'push');
            app.PayEmployeeButton.ButtonPushedFcn = createCallbackFcn(app, @PayEmployeeButtonPushed, true);
            app.PayEmployeeButton.Text = 'Pay Employee';
            app.BusinessPanel = uipanel(app.UIFigure);
            app.BusinessPanel.Title = 'Business';
            app.ViewPLButton = uibutton(app.BusinessPanel, 'push');
            app.ViewPLButton.ButtonPushedFcn = createCallbackFcn(app, @ViewPLButtonPushed, true);
            app.ViewPLButton.Text = 'View P & L';
            app.ViewBalanceSheetButton = uibutton(app.BusinessPanel, 'push');
            app.ViewBalanceSheetButton.ButtonPushedFcn = createCallbackFcn(app, @ViewBalanceSheetButtonPushed, true);
            app.ViewBalanceSheetButton.Text = 'View Balance Sheet';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BookKeeperProject

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end