classdef addEmployeeWindow < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        NumberofWithholdingEditField  matlab.ui.control.NumericEditField
        NumberofWithholdingEditFieldLabel  matlab.ui.control.Label
        SalaryEditField               matlab.ui.control.NumericEditField
        SalaryEditFieldLabel          matlab.ui.control.Label
        SSNEditField                  matlab.ui.control.EditField
        SocialSecurityNumberEditFieldLabel  matlab.ui.control.Label
        ZipCodeEditField              matlab.ui.control.NumericEditField
        ZipCodeEditFieldLabel         matlab.ui.control.Label
        StateEditField                matlab.ui.control.EditField
        StateEditFieldLabel           matlab.ui.control.Label
        CityEditField                 matlab.ui.control.EditField
        CityEditFieldLabel            matlab.ui.control.Label
        Address2EditField             matlab.ui.control.EditField
        Address2EditFieldLabel        matlab.ui.control.Label
        Address1EditField             matlab.ui.control.EditField
        Address1EditFieldLabel        matlab.ui.control.Label
        LastNameEditField             matlab.ui.control.EditField
        LastNameEditFieldLabel        matlab.ui.control.Label
        FirstNameEditField            matlab.ui.control.EditField
        FirstNameEditFieldLabel       matlab.ui.control.Label
        CancelButton                  matlab.ui.control.Button
        AddButton                     matlab.ui.control.Button
    end

    
    properties (Access = private)
        mainApp
        update
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, caller, update)
            app.mainApp = caller;
            app.update = update;
        end

        % Button pushed function: AddButton
        function AddButtonPushed(app, event)
%             out = struct('FirstName', app.FirstNameEditField.Value, 'LastName', app.LastNameEditField.Value, "Address1", app.Address1EditField.Value, "Address2", app.Address2EditField.Value, ...
%                 'City', app.CityEditField.Value, 'State', app.StateEditField.Value, 'Zip', app.ZipCodeEditField.Value, 'SSN', app.SSNEditField.Value, 'NumOfWitholding', app.NumberofWithholdingEditField.Value, ...
%                 'Salary', app.SalaryEditField.Value);
%             data = app.employees.data;
%             data(end + 1, :) = {app.FirstNameEditField.Value, app.LastNameEditField.Value, app.Address1EditField.Value, app.Address2EditField.Value, ...
%                 app.CityEditField.Value, app.StateEditField.Value, app.ZipCodeEditField.Value, app.SSNEditField.Value, app.NumberofWithholdingEditField.Value, ...
%                  app.SalaryEditField.Value};
            data = {app.FirstNameEditField.Value, app.LastNameEditField.Value, app.Address1EditField.Value, app.Address2EditField.Value, ...
                app.CityEditField.Value, app.StateEditField.Value, app.ZipCodeEditField.Value, app.SSNEditField.Value, app.NumberofWithholdingEditField.Value, ...
                 app.SalaryEditField.Value};
            insertEmployee(data);
            app.mainApp.AddEmployeesButton.Enable = 'on';
            if app.update == true
                newData = load("employeesData.mat").data;
                app.mainApp.EmployeeTable.Data = newData;
            end
            delete(app)
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.mainApp.AddEmployeesButton.Enable = 'on';
            delete(app);  
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            app.mainApp.AddEmployeesButton.Enable = 'on';
            delete(app);  
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.AddButton = uibutton(app.UIFigure, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.AddButton.Text = 'Add';
            app.CancelButton = uibutton(app.UIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.FirstNameEditFieldLabel = uilabel(app.UIFigure);
            app.FirstNameEditFieldLabel.Text = 'First Name';
            app.FirstNameEditField = uieditfield(app.UIFigure, 'text');
            app.FirstNameEditField.CharacterLimits = [0 20];
            app.FirstNameEditField.Placeholder = 'ex: John';
            app.LastNameEditFieldLabel = uilabel(app.UIFigure);
            app.LastNameEditFieldLabel.Text = 'Last Name';
            app.LastNameEditField = uieditfield(app.UIFigure, 'text');
            app.LastNameEditField.CharacterLimits = [0 20];
            app.LastNameEditField.Placeholder = 'ex: Smith';
            app.Address1EditFieldLabel = uilabel(app.UIFigure);
            app.Address1EditFieldLabel.Text = 'Address 1';
            app.Address1EditField = uieditfield(app.UIFigure, 'text');
            app.Address2EditFieldLabel = uilabel(app.UIFigure);
            app.Address2EditFieldLabel.Text = 'Address 2';
            app.Address2EditField = uieditfield(app.UIFigure, 'text');
            app.CityEditFieldLabel = uilabel(app.UIFigure);
            app.CityEditFieldLabel.Text = 'City';
            app.CityEditField = uieditfield(app.UIFigure, 'text');
            app.CityEditField.CharacterLimits = [0 10];
            app.StateEditFieldLabel = uilabel(app.UIFigure);
            app.StateEditFieldLabel.Text = 'State';
            app.StateEditField = uieditfield(app.UIFigure, 'text');
            app.StateEditField.CharacterLimits = [0 10];
            app.ZipCodeEditFieldLabel = uilabel(app.UIFigure);
            app.ZipCodeEditFieldLabel.Text = 'Zip Code';
            app.ZipCodeEditField = uieditfield(app.UIFigure, 'numeric');
            app.SocialSecurityNumberEditFieldLabel = uilabel(app.UIFigure);
            app.SocialSecurityNumberEditFieldLabel.Text = 'Social Security Number';
            app.SSNEditField = uieditfield(app.UIFigure, 'text');
            app.SalaryEditFieldLabel = uilabel(app.UIFigure);
            app.SalaryEditFieldLabel.Text = 'Salary';
            app.SalaryEditField = uieditfield(app.UIFigure, 'numeric');
            app.SalaryEditField.Limits = [0 Inf];
            app.NumberofWithholdingEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofWithholdingEditFieldLabel.Text = 'Number of Withholding';
            app.NumberofWithholdingEditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = addEmployeeWindow(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end