classdef addInvoiceWindow < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TransactionLabel              matlab.ui.control.Label
        NumberofUnitstoInvoiceEditField  matlab.ui.control.NumericEditField
        NumberofUnitstoInvoiceEditFieldLabel  matlab.ui.control.Label
        CustomerListBox               matlab.ui.control.ListBox
        CustomerListBoxLabel          matlab.ui.control.Label
        UnitsavailableEditField       matlab.ui.control.NumericEditField
        UnitsavailableEditFieldLabel  matlab.ui.control.Label
        CancelButton                  matlab.ui.control.Button
        AddButton                     matlab.ui.control.Button
    end

    
    properties (Access = private)
        mainApp
        update
        idx
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, caller, update)
            app.mainApp = caller;
            app.update = update;
            generateCogs();
            customer = load('customersData.mat').data;
            [row, ~] = size(customer);
            for i = 1 : row
                parts{i} = customer{i, 1};
            end
%             parts = ["Tires", "Mixer", "Body Casting", "Cab Casting", "Windshield", "Axles", "Rims", "Screw", "Box", "Labor", "Master Pack"];
            app.CustomerListBox.Items = parts;
            cogs = load('cogData.mat').data;
            app.UnitsavailableEditField.Value = double(cogs(2));
        end

        % Button pushed function: AddButton
        function AddButtonPushed(app, event)
            app.TransactionLabel.Visible = "on";
            app.TransactionLabel.Text = "Transaction Processing...";
            state = createInvoice(app.idx, app.NumberofUnitstoInvoiceEditField.Value);
            if state == true
                app.TransactionLabel.Text = "Transaction Complete!";
                app.UnitsavailableEditField.Value = app.UnitsavailableEditField.Value - app.NumberofUnitstoInvoiceEditField.Value;
            else 
                app.TransactionLabel.FontColor = 'r';
                app.TransactionLabel.Text = "Transaction Failed!";
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.mainApp.CreateInvoiceButton.Enable = 'on';
            delete(app);  
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            app.mainApp.CreateInvoiceButton.Enable = 'on';
            delete(app)
        end

        % Value changed function: NumberofUnitstoInvoiceEditField
        function NumberofUnitstoInvoiceEditFieldValueChanged(app, event)
            value = app.NumberofUnitstoInvoiceEditField.Value;
            app.AddButton.Enable = "on";
        end

        % Clicked callback: CustomerListBox
        function CustomerListBoxClicked(app, event)
            item = event.InteractionInformation.Item;
            app.idx = item;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.AddButton = uibutton(app.UIFigure, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.AddButton.Enable = 'off';
            app.AddButton.Text = 'Add';
            app.CancelButton = uibutton(app.UIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.UnitsavailableEditFieldLabel = uilabel(app.UIFigure);
            app.UnitsavailableEditFieldLabel.Text = 'Units available';
            app.UnitsavailableEditField = uieditfield(app.UIFigure, 'numeric');
            app.UnitsavailableEditField.Limits = [0 Inf];
            app.UnitsavailableEditField.ValueDisplayFormat = '%.0f';
            app.UnitsavailableEditField.Editable = 'off';
            app.CustomerListBoxLabel = uilabel(app.UIFigure);
            app.CustomerListBoxLabel.Text = 'Customer';
            app.CustomerListBox = uilistbox(app.UIFigure);
            app.CustomerListBox.ClickedFcn = createCallbackFcn(app, @CustomerListBoxClicked, true);
            app.NumberofUnitstoInvoiceEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofUnitstoInvoiceEditFieldLabel.Text = 'Number of Units to Invoice';
            app.NumberofUnitstoInvoiceEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumberofUnitstoInvoiceEditField.Limits = [0 Inf];
            app.NumberofUnitstoInvoiceEditField.ValueDisplayFormat = '%.0f';
            app.NumberofUnitstoInvoiceEditField.ValueChangedFcn = createCallbackFcn(app, @NumberofUnitstoInvoiceEditFieldValueChanged, true);
            app.TransactionLabel = uilabel(app.UIFigure);
            app.TransactionLabel.Visible = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = addInvoiceWindow(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end