classdef addVendorWindow < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        PriceEditField            matlab.ui.control.NumericEditField
        PriceEditFieldLabel       matlab.ui.control.Label
        PartNameEditField         matlab.ui.control.EditField
        PartNmeLabel              matlab.ui.control.Label
        ZipCodeEditField          matlab.ui.control.NumericEditField
        ZipCodeEditFieldLabel     matlab.ui.control.Label
        StateEditField            matlab.ui.control.EditField
        StateEditFieldLabel       matlab.ui.control.Label
        CityEditField             matlab.ui.control.EditField
        CityEditFieldLabel        matlab.ui.control.Label
        Address2EditField         matlab.ui.control.EditField
        Address2EditFieldLabel    matlab.ui.control.Label
        Address1EditField         matlab.ui.control.EditField
        Address1EditFieldLabel    matlab.ui.control.Label
        VendorNameEditField       matlab.ui.control.EditField
        VendorNameEditFieldLabel  matlab.ui.control.Label
        CancelButton              matlab.ui.control.Button
        AddButton                 matlab.ui.control.Button
    end

    
    properties (Access = private)
        mainApp
        update
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, caller, update)
            app.mainApp = caller;
            app.update = update;
        end

        % Button pushed function: AddButton
        function AddButtonPushed(app, event)
            data = {app.VendorNameEditField.Value, app.PartNameEditField.Value, app.PriceEditField.Value, app.Address1EditField.Value, app.Address2EditField.Value, ...
                app.CityEditField.Value, app.StateEditField.Value, app.ZipCodeEditField.Value};
            insertVendor(data);
            app.mainApp.AddVendorsButton.Enable = 'on';
            if app.update == true
                newData = load("vendorsData.mat").data;
                app.mainApp.VendorTable.Data = newData;
            end
            delete(app)
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.mainApp.AddVendorsButton.Enable = 'on';
            delete(app);  
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            app.mainApp.AddVendorsButton.Enable = 'on';
            delete(app);  
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.AddButton = uibutton(app.UIFigure, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.AddButton.Text = 'Add';
            app.CancelButton = uibutton(app.UIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.VendorNameEditFieldLabel = uilabel(app.UIFigure);
            app.VendorNameEditFieldLabel.Text = 'Vendor Name';
            app.VendorNameEditField = uieditfield(app.UIFigure, 'text');
            app.VendorNameEditField.CharacterLimits = [0 20];
            app.VendorNameEditField.Placeholder = 'ex: John';
            app.Address1EditFieldLabel = uilabel(app.UIFigure);
            app.Address1EditFieldLabel.Text = 'Address 1';
            app.Address1EditField = uieditfield(app.UIFigure, 'text');
            app.Address2EditFieldLabel = uilabel(app.UIFigure);
            app.Address2EditFieldLabel.Text = 'Address 2';
            app.Address2EditField = uieditfield(app.UIFigure, 'text');
            app.CityEditFieldLabel = uilabel(app.UIFigure);
            app.CityEditFieldLabel.Text = 'City';
            app.CityEditField = uieditfield(app.UIFigure, 'text');
            app.CityEditField.CharacterLimits = [0 10];
            app.StateEditFieldLabel = uilabel(app.UIFigure);
            app.StateEditFieldLabel.Text = 'State';
            app.StateEditField = uieditfield(app.UIFigure, 'text');
            app.StateEditField.CharacterLimits = [0 10];
            app.ZipCodeEditFieldLabel = uilabel(app.UIFigure);
            app.ZipCodeEditFieldLabel.Text = 'Zip Code';
            app.ZipCodeEditField = uieditfield(app.UIFigure, 'numeric');
            app.PartNmeLabel = uilabel(app.UIFigure);
            app.PartNmeLabel.Text = 'Part Name';
            app.PartNameEditField = uieditfield(app.UIFigure, 'text');
            app.PriceEditFieldLabel = uilabel(app.UIFigure);
            app.PriceEditFieldLabel.Text = 'Price';
            app.PriceEditField = uieditfield(app.UIFigure, 'numeric');
            app.PriceEditField.Limits = [0 Inf];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = addVendorWindow(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end