classdef balanceSheetWindow < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TotalLiabilityNetWorthEditField  matlab.ui.control.NumericEditField
        TotalLiabilityNetWorthEditFieldLabel  matlab.ui.control.Label
        Label_26                        matlab.ui.control.Label
        TotalLongTermDebtEditField      matlab.ui.control.NumericEditField
        TotalLongTermDebtEditFieldLabel  matlab.ui.control.Label
        Label_25                        matlab.ui.control.Label
        NetWorthEditField               matlab.ui.control.NumericEditField
        NetWorthEditFieldLabel          matlab.ui.control.Label
        TotalLiabilitiesEditField       matlab.ui.control.NumericEditField
        TotalLiabilitiesEditFieldLabel  matlab.ui.control.Label
        MortgageEditField               matlab.ui.control.NumericEditField
        MortgageEditFieldLabel          matlab.ui.control.Label
        AccountPaybleEditField          matlab.ui.control.NumericEditField
        AccountPaybleEditFieldLabel     matlab.ui.control.Label
        TotalCurrentLiabilitiesEditField  matlab.ui.control.NumericEditField
        TotalCurrentLiabilitiesEditFieldLabel  matlab.ui.control.Label
        POPayableEditField              matlab.ui.control.NumericEditField
        POPayableEditFieldLabel         matlab.ui.control.Label
        Panel_4                         matlab.ui.container.Panel
        LiabilitiesNetWorthLabel        matlab.ui.control.Label
        Label_24                        matlab.ui.control.Label
        Label_20                        matlab.ui.control.Label
        Label_17                        matlab.ui.control.Label
        Label_16                        matlab.ui.control.Label
        Label_15                        matlab.ui.control.Label
        Label_13                        matlab.ui.control.Label
        Panel_2                         matlab.ui.container.Panel
        TotalAssetsEditField            matlab.ui.control.NumericEditField
        TotalAssetsEditFieldLabel       matlab.ui.control.Label
        TotalFixedAssetsEditField       matlab.ui.control.NumericEditField
        TotalFixedAssetsEditFieldLabel  matlab.ui.control.Label
        FurnitureandFixturesEditField   matlab.ui.control.NumericEditField
        FurnitureandFixturesEditFieldLabel  matlab.ui.control.Label
        EquipmentEditField              matlab.ui.control.NumericEditField
        EquipmentEditFieldLabel         matlab.ui.control.Label
        LandBuildingsEditField          matlab.ui.control.NumericEditField
        LandBuildingsEditFieldLabel     matlab.ui.control.Label
        InventoryEditField              matlab.ui.control.NumericEditField
        InventoryEditFieldLabel         matlab.ui.control.Label
        TotalCurrentAssetsEditField     matlab.ui.control.NumericEditField
        TotalCurrentAssetsEditFieldLabel  matlab.ui.control.Label
        AccountsReceivableEditField     matlab.ui.control.NumericEditField
        AccountsReceivableEditFieldLabel  matlab.ui.control.Label
        CashEditField                   matlab.ui.control.NumericEditField
        CashEditFieldLabel              matlab.ui.control.Label
        Panel_3                         matlab.ui.container.Panel
        AssetsLabel                     matlab.ui.control.Label
        Label_12                        matlab.ui.control.Label
        Label_8                         matlab.ui.control.Label
        Label_7                         matlab.ui.control.Label
        Label_6                         matlab.ui.control.Label
        Label_5                         matlab.ui.control.Label
        Label_4                         matlab.ui.control.Label
        Label_3                         matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        Label                           matlab.ui.control.Label
    end

    
    properties (Access = private)
        mainApp
    end
    
    methods (Access = private)
        
        function loadBalanceSheet(app)
            generateIncomestatement();
            generateBalanceSheet();
            data = load('balanceSheetData.mat').data;
            app.CashEditField.Value = data.cash;
            app.AccountsReceivableEditField.Value = data.receivable;
            app.TotalCurrentAssetsEditField.Value = data.total_current_assets;
            app.InventoryEditField.Value = data.inventory;
            app.LandBuildingsEditField.Value = data.building;
            app.FurnitureandFixturesEditField.Value = data.furniture;
            app.EquipmentEditField.Value = data.equipment;
            app.TotalFixedAssetsEditField.Value = data.total_fixed_assets;
            app.TotalAssetsEditField.Value = data.total_assets;
            app.POPayableEditField.Value = data.po_payable;
            app.AccountPaybleEditField.Value = data.account_payable;
            app.MortgageEditField.Value = data.mortgage;
            app.TotalLongTermDebtEditField.Value = data.total_long_term_debt;
            app.TotalLiabilitiesEditField.Value = data.total_liabilities;
            app.TotalCurrentLiabilitiesEditField.Value = data.total_current_liability;
            app.NetWorthEditField.Value = data.net_worth;
            app.TotalLiabilityNetWorthEditField.Value = data.tlnw;
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, caller, update)
            app.mainApp = caller;
            app.loadBalanceSheet();
        end

        % Callback function
        function AddButtonPushed(app, event)
            data = {app.VendorNameEditField.Value, app.PartNameEditField.Value, app.Address1EditField.Value, app.Address2EditField.Value, ...
                app.CityEditField.Value, app.StateEditField.Value, app.CashEditField.Value, app.PriceEditField.Value};
            insertVendor(data);
            app.mainApp.AddVendorsButton.Enable = 'on';
            if app.update == true
                newData = load("vendorsData.mat").data;
                app.mainApp.VendorTable.Data = newData;
            end
            delete(app)
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.mainApp.ViewBalanceSheetButton.Enable = 'on';
            delete(app);  
        end

        % Callback function
        function CancelButtonPushed(app, event)
            app.mainApp.AddVendorsButton.Enable = 'on';
            delete(app);  
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Panel_2 = uipanel(app.UIFigure);
            app.Label = uilabel(app.Panel_2);
            app.Label.Text = '$';
            app.Label_2 = uilabel(app.Panel_2);
            app.Label_2.Text = '$';
            app.Label_3 = uilabel(app.Panel_2);
            app.Label_3.Text = '$';
            app.Label_4 = uilabel(app.Panel_2);
            app.Label_4.Text = '$';
            app.Label_5 = uilabel(app.Panel_2);
            app.Label_5.Text = '$';
            app.Label_6 = uilabel(app.Panel_2);
            app.Label_6.Text = '$';
            app.Label_7 = uilabel(app.Panel_2);
            app.Label_7.Text = '$';
            app.Label_8 = uilabel(app.Panel_2);
            app.Label_8.Text = '$';
            app.Label_12 = uilabel(app.Panel_2);
            app.Label_12.Text = '$';
            app.Panel_3 = uipanel(app.Panel_2);
            app.AssetsLabel = uilabel(app.Panel_3);
            app.AssetsLabel.Text = 'Assets';
            app.CashEditFieldLabel = uilabel(app.Panel_2);
            app.CashEditFieldLabel.Text = 'Cash';
            app.CashEditField = uieditfield(app.Panel_2, 'numeric');
            app.CashEditField.ValueDisplayFormat = '%.2f';
            app.CashEditField.Editable = 'off';
            app.AccountsReceivableEditFieldLabel = uilabel(app.Panel_2);
            app.AccountsReceivableEditFieldLabel.Text = 'Accounts Receivable';
            app.AccountsReceivableEditField = uieditfield(app.Panel_2, 'numeric');
            app.AccountsReceivableEditField.ValueDisplayFormat = '%.2f';
            app.AccountsReceivableEditField.Editable = 'off';
            app.TotalCurrentAssetsEditFieldLabel = uilabel(app.Panel_2);
            app.TotalCurrentAssetsEditFieldLabel.Text = 'Total Current Assets';
            app.TotalCurrentAssetsEditField = uieditfield(app.Panel_2, 'numeric');
            app.TotalCurrentAssetsEditField.ValueDisplayFormat = '%.2f';
            app.TotalCurrentAssetsEditField.Editable = 'off';
            app.InventoryEditFieldLabel = uilabel(app.Panel_2);
            app.InventoryEditFieldLabel.Text = 'Inventory';
            app.InventoryEditField = uieditfield(app.Panel_2, 'numeric');
            app.InventoryEditField.ValueDisplayFormat = '%.2f';
            app.InventoryEditField.Editable = 'off';
            app.LandBuildingsEditFieldLabel = uilabel(app.Panel_2);
            app.LandBuildingsEditFieldLabel.Text = 'Land/Buildings';
            app.LandBuildingsEditField = uieditfield(app.Panel_2, 'numeric');
            app.LandBuildingsEditField.ValueDisplayFormat = '%.2f';
            app.LandBuildingsEditField.Editable = 'off';
            app.EquipmentEditFieldLabel = uilabel(app.Panel_2);
            app.EquipmentEditFieldLabel.Text = 'Equipment';
            app.EquipmentEditField = uieditfield(app.Panel_2, 'numeric');
            app.EquipmentEditField.ValueDisplayFormat = '%.2f';
            app.EquipmentEditField.Editable = 'off';
            app.FurnitureandFixturesEditFieldLabel = uilabel(app.Panel_2);
            app.FurnitureandFixturesEditFieldLabel.Text = 'Furniture and Fixtures';
            app.FurnitureandFixturesEditField = uieditfield(app.Panel_2, 'numeric');
            app.FurnitureandFixturesEditField.ValueDisplayFormat = '%.2f';
            app.FurnitureandFixturesEditField.Editable = 'off';
            app.TotalFixedAssetsEditFieldLabel = uilabel(app.Panel_2);
            app.TotalFixedAssetsEditFieldLabel.Text = 'Total Fixed Assets';
            app.TotalFixedAssetsEditField = uieditfield(app.Panel_2, 'numeric');
            app.TotalFixedAssetsEditField.ValueDisplayFormat = '%.2f';
            app.TotalFixedAssetsEditField.Editable = 'off';
            app.TotalAssetsEditFieldLabel = uilabel(app.Panel_2);
            app.TotalAssetsEditFieldLabel.Text = 'Total Assets';
            app.TotalAssetsEditField = uieditfield(app.Panel_2, 'numeric');
            app.TotalAssetsEditField.ValueDisplayFormat = '%.2f';
            app.TotalAssetsEditField.Editable = 'off';
            app.Label_13 = uilabel(app.UIFigure);
            app.Label_13.Text = '$';
            app.Label_15 = uilabel(app.UIFigure);
            app.Label_15.Text = '$';
            app.Label_16 = uilabel(app.UIFigure);
            app.Label_16.Text = '$';
            app.Label_17 = uilabel(app.UIFigure);
            app.Label_17.Text = '$';
            app.Label_20 = uilabel(app.UIFigure);
            app.Label_20.Text = '$';
            app.Label_24 = uilabel(app.UIFigure);
            app.Label_24.Text = '$';
            app.Panel_4 = uipanel(app.UIFigure);
            app.LiabilitiesNetWorthLabel = uilabel(app.Panel_4);
            app.LiabilitiesNetWorthLabel.Text = 'Liabilities & Net Worth';
            app.POPayableEditFieldLabel = uilabel(app.UIFigure);
            app.POPayableEditFieldLabel.Text = 'PO Payable';
            app.POPayableEditField = uieditfield(app.UIFigure, 'numeric');
            app.POPayableEditField.ValueDisplayFormat = '%.2f';
            app.POPayableEditField.Editable = 'off';
            app.TotalCurrentLiabilitiesEditFieldLabel = uilabel(app.UIFigure);
            app.TotalCurrentLiabilitiesEditFieldLabel.Text = 'Total Current Liabilities';
            app.TotalCurrentLiabilitiesEditField = uieditfield(app.UIFigure, 'numeric');
            app.TotalCurrentLiabilitiesEditField.ValueDisplayFormat = '%.2f';
            app.TotalCurrentLiabilitiesEditField.Editable = 'off';
            app.AccountPaybleEditFieldLabel = uilabel(app.UIFigure);
            app.AccountPaybleEditFieldLabel.Text = 'Account Payble';
            app.AccountPaybleEditField = uieditfield(app.UIFigure, 'numeric');
            app.AccountPaybleEditField.ValueDisplayFormat = '%.2f';
            app.AccountPaybleEditField.Editable = 'off';
            app.MortgageEditFieldLabel = uilabel(app.UIFigure);
            app.MortgageEditFieldLabel.Text = 'Mortgage';
            app.MortgageEditField = uieditfield(app.UIFigure, 'numeric');
            app.MortgageEditField.ValueDisplayFormat = '%.2f';
            app.MortgageEditField.Editable = 'off';
            app.TotalLiabilitiesEditFieldLabel = uilabel(app.UIFigure);
            app.TotalLiabilitiesEditFieldLabel.Text = 'Total Liabilities';
            app.TotalLiabilitiesEditField = uieditfield(app.UIFigure, 'numeric');
            app.TotalLiabilitiesEditField.ValueDisplayFormat = '%.2f';
            app.TotalLiabilitiesEditField.Editable = 'off';
            app.NetWorthEditFieldLabel = uilabel(app.UIFigure);
            app.NetWorthEditFieldLabel.Text = 'Net Worth';
            app.NetWorthEditField = uieditfield(app.UIFigure, 'numeric');
            app.NetWorthEditField.ValueDisplayFormat = '%.2f';
            app.NetWorthEditField.Editable = 'off';
            app.Label_25 = uilabel(app.UIFigure);
            app.Label_25.Text = '$';
            app.TotalLongTermDebtEditFieldLabel = uilabel(app.UIFigure);
            app.TotalLongTermDebtEditFieldLabel.Text = 'Total Long Term Debt';
            app.TotalLongTermDebtEditField = uieditfield(app.UIFigure, 'numeric');
            app.TotalLongTermDebtEditField.ValueDisplayFormat = '%.2f';
            app.TotalLongTermDebtEditField.Editable = 'off';
            app.Label_26 = uilabel(app.UIFigure);
            app.Label_26.Text = '$';
            app.TotalLiabilityNetWorthEditFieldLabel = uilabel(app.UIFigure);
            app.TotalLiabilityNetWorthEditFieldLabel.Text = 'Total Liability & Net Worth';
            app.TotalLiabilityNetWorthEditField = uieditfield(app.UIFigure, 'numeric');
            app.TotalLiabilityNetWorthEditField.ValueDisplayFormat = '%.2f';
            app.TotalLiabilityNetWorthEditField.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = balanceSheetWindow(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end