classdef createPoWindow < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        TransactionLabel           matlab.ui.control.Label
        QuantityEditField          matlab.ui.control.NumericEditField
        QuantityEditFieldLabel     matlab.ui.control.Label
        PartsListBox               matlab.ui.control.ListBox
        SelectPartToPurchaseLabel  matlab.ui.control.Label
        CancelButton               matlab.ui.control.Button
        PayButton                  matlab.ui.control.Button
    end

    
    properties (Access = private)
        mainApp
        parts
        update
        idx % The index of current employee clicked
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, caller, update)
            app.mainApp = caller;
            app.update = update;
            app.PayButton.Enable = "off";
            app.parts = ["Tires", "Mixer", "Body Casting", "Cab Casting", "Windshield", "Axles", "Rims", "Screw", "Box", "Labor", "Master Pack"];
            app.PartsListBox.Items = app.parts;
        end

        % Button pushed function: PayButton
        function PayButtonPushed(app, event)
            app.TransactionLabel.Visible = "on";
            app.TransactionLabel.Text = "Transaction Processing...";
            state = purchaseParts(app.idx, app.QuantityEditField.Value);
            if state == true
                app.TransactionLabel.Text = "Transaction Complete!";
            else 
                app.TransactionLabel.FontColor = 'r';
                app.TransactionLabel.Text = "Transaction Failed!";
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.mainApp.CreatePOButton.Enable = 'on';
            delete(app);  
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            app.mainApp.CreatePOButton.Enable = 'on';
            delete(app);  
        end

        % Clicked callback: PartsListBox
        function PartsListBoxClicked(app, event)
            item = event.InteractionInformation.Item;
            app.idx = item;
            app.PayButton.Enable = "on";
            app.TransactionLabel.Enable = "off";
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.PayButton = uibutton(app.UIFigure, 'push');
            app.PayButton.ButtonPushedFcn = createCallbackFcn(app, @PayButtonPushed, true);
            app.PayButton.Text = 'Pay';
            app.CancelButton = uibutton(app.UIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.SelectPartToPurchaseLabel = uilabel(app.UIFigure);
            app.SelectPartToPurchaseLabel.Text = 'Select Part  To Purchase';
            app.PartsListBox = uilistbox(app.UIFigure);
            app.PartsListBox.ClickedFcn = createCallbackFcn(app, @PartsListBoxClicked, true);
            app.QuantityEditFieldLabel = uilabel(app.UIFigure);
            app.QuantityEditFieldLabel.Text = 'Quantity';
            app.QuantityEditField = uieditfield(app.UIFigure, 'numeric');
            app.TransactionLabel = uilabel(app.UIFigure);
            app.TransactionLabel.Visible = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = createPoWindow(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end