classdef customerWindow < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        RemoveCustomerButton  matlab.ui.control.Button
        AddCustomersButton    matlab.ui.control.Button
        CustomerTable         matlab.ui.control.Table
    end

    
    properties (Access = private)
        mainApp
        addCustomerWindow
        customers
        columnNames
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, caller, colors)
            app.mainApp = caller;
            app.CustomerTable.BackgroundColor = colors('panelColor');
            app.customers = load("customersData.mat").data;
            app.columnNames = ["Company Name", "Last Name", "First Name", "Address Line 1", "Address Line 2", "City", "State", ...
            "Zip Code", "Price"];
            app.CustomerTable.ColumnName = app.columnNames;
            app.CustomerTable.Data = app.customers;
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.mainApp.ViewCustomersButton.Enable = 'on';
            delete(app);  
        end

        % Button pushed function: AddCustomersButton
        function AddCustomersButtonPushed(app, event)
            app.AddCustomersButton.Enable = 'off';
            
            % Open the options dialog and pass inputs
            app.addCustomerWindow = addCustomerWindow(app, true);
        end

        % Button pushed function: RemoveCustomerButton
        function RemoveCustomerButtonPushed(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.CustomerTable = uitable(app.UIFigure);
            app.CustomerTable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.CustomerTable.RowName = {};
            app.CustomerTable.SelectionType = 'row';
            app.AddCustomersButton = uibutton(app.UIFigure, 'push');
            app.AddCustomersButton.ButtonPushedFcn = createCallbackFcn(app, @AddCustomersButtonPushed, true);
            app.AddCustomersButton.Text = 'Add Customers';
            app.RemoveCustomerButton = uibutton(app.UIFigure, 'push');
            app.RemoveCustomerButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveCustomerButtonPushed, true);
            app.RemoveCustomerButton.Text = 'Remove Customer';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = customerWindow(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end