classdef employeeWIndow < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        RemoveEmployeeButton  matlab.ui.control.Button
        AddEmployeesButton    matlab.ui.control.Button
        EmployeeTable         matlab.ui.control.Table
    end

    
    properties (Access = private)
        mainApp
        addEmployeeWindow
        employees
        columnNames
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, caller, colors)
            app.mainApp = caller;
            app.EmployeeTable.BackgroundColor = colors('panelColor');
            app.employees = load("employeesData.mat").data;
            app.columnNames = ["First Name", "Last Name", "Address Line 1", "Address Line 2", "City", "State", ...
            "Zip Code", "SSN", "# of Witholding", "Salary"];
            app.EmployeeTable.ColumnName = app.columnNames;
            app.EmployeeTable.Data = app.employees;
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.mainApp.ViewEmployeesButton.Enable = 'on';
            delete(app);  
        end

        % Button pushed function: AddEmployeesButton
        function AddEmployeesButtonPushed(app, event)
            app.AddEmployeesButton.Enable = 'off';
            
            % Open the options dialog and pass inputs
            app.addEmployeeWindow = addEmployeeWindow(app, true);
        end

        % Button pushed function: RemoveEmployeeButton
        function RemoveEmployeeButtonPushed(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.EmployeeTable = uitable(app.UIFigure);
            app.EmployeeTable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.EmployeeTable.RowName = {};
            app.EmployeeTable.SelectionType = 'row';
            app.AddEmployeesButton = uibutton(app.UIFigure, 'push');
            app.AddEmployeesButton.ButtonPushedFcn = createCallbackFcn(app, @AddEmployeesButtonPushed, true);
            app.AddEmployeesButton.Text = 'Add Employee';
            app.RemoveEmployeeButton = uibutton(app.UIFigure, 'push');
            app.RemoveEmployeeButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveEmployeeButtonPushed, true);
            app.RemoveEmployeeButton.Text = 'Remove Employee';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = employeeWIndow(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end