classdef incomeWindow < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        Panel                          matlab.ui.container.Panel
        IncomeStatementLabel           matlab.ui.control.Label
        NetIncomeEditField             matlab.ui.control.NumericEditField
        NetIncomeEditFieldLabel        matlab.ui.control.Label
        Label_12                       matlab.ui.control.Label
        IncomeTaxesEditField           matlab.ui.control.NumericEditField
        IncomeTaxesEditFieldLabel      matlab.ui.control.Label
        OperatingIncomeEditField       matlab.ui.control.NumericEditField
        OperatingIncomeEditFieldLabel  matlab.ui.control.Label
        OtherIncomeEditField           matlab.ui.control.NumericEditField
        OtherIncomeEditFieldLabel      matlab.ui.control.Label
        Label_11                       matlab.ui.control.Label
        Label_10                       matlab.ui.control.Label
        Label_9                        matlab.ui.control.Label
        TotalExpensesEditField         matlab.ui.control.NumericEditField
        TotalExpensesEditFieldLabel    matlab.ui.control.Label
        Label_8                        matlab.ui.control.Label
        Label_7                        matlab.ui.control.Label
        Label_6                        matlab.ui.control.Label
        Label_5                        matlab.ui.control.Label
        Label_4                        matlab.ui.control.Label
        AnnualExpensesEditField        matlab.ui.control.NumericEditField
        AnnualExpensesEditFieldLabel   matlab.ui.control.Label
        BillsEditField                 matlab.ui.control.NumericEditField
        BillsEditFieldLabel            matlab.ui.control.Label
        PayrollWithholdingEditField    matlab.ui.control.NumericEditField
        PayrollWithholdingEditFieldLabel  matlab.ui.control.Label
        PayrollEditField               matlab.ui.control.NumericEditField
        PayrollEditFieldLabel          matlab.ui.control.Label
        GrossProfitEditFieldLabel_2    matlab.ui.control.Label
        GrossProfitEditField           matlab.ui.control.NumericEditField
        GrossProfitEditFieldLabel      matlab.ui.control.Label
        Label_3                        matlab.ui.control.Label
        COGSEditField                  matlab.ui.control.NumericEditField
        COGSEditFieldLabel             matlab.ui.control.Label
        Label_2                        matlab.ui.control.Label
        Label                          matlab.ui.control.Label
        SalesEditField                 matlab.ui.control.NumericEditField
        SalesEditFieldLabel            matlab.ui.control.Label
    end

    
    properties (Access = private)
        mainApp
        update
    end
    
    methods (Access = private)
        
        function loadIncomeStatement(app)
            generateIncomestatement();
            data = load('incomeStatementData.mat').data;
            app.SalesEditField.Value = double(data.sales);
            app.COGSEditField.Value = double(data.COG);
            app.GrossProfitEditField.Value = data.gross_profit;
            app.PayrollEditField.Value = data.payroll;
            app.PayrollWithholdingEditField.Value = data.payroll_withholding;
            app.AnnualExpensesEditField.Value = data.annual_expenses;
            app.BillsEditField.Value = data.bills;
            app.TotalExpensesEditField.Value = data.total_expenses;
            app.OtherIncomeEditField.Value = data.other_income;
            app.OperatingIncomeEditField.Value = data.operating_income;
            app.NetIncomeEditField.Value = data.net_income;
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, caller, update)
            app.mainApp = caller;
            app.update = update;
            app.loadIncomeStatement();
        end

        % Callback function: not associated with a component
        function AddButtonPushed(app, event)
            data = {app.VendorNameEditField.Value, app.PartNameEditField.Value, app.Address1EditField.Value, app.Address2EditField.Value, ...
                app.CityEditField.Value, app.StateEditField.Value, app.SalesEditField.Value, app.PriceEditField.Value};
            insertVendor(data);
            app.mainApp.AddVendorsButton.Enable = 'on';
            if app.update == true
                newData = load("vendorsData.mat").data;
                app.mainApp.VendorTable.Data = newData;
            end
            delete(app)
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.mainApp.ViewPLButton.Enable = 'on';
            delete(app);  
        end

        % Callback function: not associated with a component
        function CancelButtonPushed(app, event)
            app.mainApp.AddVendorsButton.Enable = 'on';
            delete(app);  
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.SalesEditFieldLabel = uilabel(app.UIFigure);
            app.SalesEditFieldLabel.Text = 'Sales';
            app.SalesEditField = uieditfield(app.UIFigure, 'numeric');
            app.SalesEditField.ValueDisplayFormat = '%.2f';
            app.SalesEditField.Editable = 'off';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '$';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '$';
            app.COGSEditFieldLabel = uilabel(app.UIFigure);
            app.COGSEditFieldLabel.Text = 'COGS';
            app.COGSEditField = uieditfield(app.UIFigure, 'numeric');
            app.COGSEditField.ValueDisplayFormat = '%.2f';
            app.COGSEditField.Editable = 'off';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '$';
            app.GrossProfitEditFieldLabel = uilabel(app.UIFigure);
            app.GrossProfitEditFieldLabel.Text = 'Gross Profit';
            app.GrossProfitEditField = uieditfield(app.UIFigure, 'numeric');
            app.GrossProfitEditField.ValueDisplayFormat = '%.2f';
            app.GrossProfitEditField.Editable = 'off';
            app.GrossProfitEditFieldLabel_2 = uilabel(app.UIFigure);
            app.GrossProfitEditFieldLabel_2.Text = 'Expenses';
            app.PayrollEditFieldLabel = uilabel(app.UIFigure);
            app.PayrollEditFieldLabel.Text = 'Payroll';
            app.PayrollEditField = uieditfield(app.UIFigure, 'numeric');
            app.PayrollEditField.ValueDisplayFormat = '%.2f';
            app.PayrollEditField.Editable = 'off';
            app.PayrollWithholdingEditFieldLabel = uilabel(app.UIFigure);
            app.PayrollWithholdingEditFieldLabel.Text = 'Payroll Withholding';
            app.PayrollWithholdingEditField = uieditfield(app.UIFigure, 'numeric');
            app.PayrollWithholdingEditField.ValueDisplayFormat = '%.2f';
            app.PayrollWithholdingEditField.Editable = 'off';
            app.BillsEditFieldLabel = uilabel(app.UIFigure);
            app.BillsEditFieldLabel.Text = 'Bills';
            app.BillsEditField = uieditfield(app.UIFigure, 'numeric');
            app.BillsEditField.ValueDisplayFormat = '%.2f';
            app.BillsEditField.Editable = 'off';
            app.AnnualExpensesEditFieldLabel = uilabel(app.UIFigure);
            app.AnnualExpensesEditFieldLabel.Text = 'Annual Expenses';
            app.AnnualExpensesEditField = uieditfield(app.UIFigure, 'numeric');
            app.AnnualExpensesEditField.ValueDisplayFormat = '%.2f';
            app.AnnualExpensesEditField.Editable = 'off';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '$';
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = '$';
            app.Label_6 = uilabel(app.UIFigure);
            app.Label_6.Text = '$';
            app.Label_7 = uilabel(app.UIFigure);
            app.Label_7.Text = '$';
            app.Label_8 = uilabel(app.UIFigure);
            app.Label_8.Text = '$';
            app.TotalExpensesEditFieldLabel = uilabel(app.UIFigure);
            app.TotalExpensesEditFieldLabel.Text = 'Total Expenses';
            app.TotalExpensesEditField = uieditfield(app.UIFigure, 'numeric');
            app.TotalExpensesEditField.ValueDisplayFormat = '%.2f';
            app.TotalExpensesEditField.Editable = 'off';
            app.Label_9 = uilabel(app.UIFigure);
            app.Label_9.Text = '$';
            app.Label_10 = uilabel(app.UIFigure);
            app.Label_10.Text = '$';
            app.Label_11 = uilabel(app.UIFigure);
            app.Label_11.Text = '$';
            app.OtherIncomeEditFieldLabel = uilabel(app.UIFigure);
            app.OtherIncomeEditFieldLabel.Text = 'Other Income';
            app.OtherIncomeEditField = uieditfield(app.UIFigure, 'numeric');
            app.OtherIncomeEditField.ValueDisplayFormat = '%.2f';
            app.OtherIncomeEditField.Editable = 'off';
            app.OperatingIncomeEditFieldLabel = uilabel(app.UIFigure);
            app.OperatingIncomeEditFieldLabel.Text = 'Operating Income';
            app.OperatingIncomeEditField = uieditfield(app.UIFigure, 'numeric');
            app.OperatingIncomeEditField.ValueDisplayFormat = '%.2f';
            app.OperatingIncomeEditField.Editable = 'off';
            app.IncomeTaxesEditFieldLabel = uilabel(app.UIFigure);
            app.IncomeTaxesEditFieldLabel.Text = 'Income Taxes';
            app.IncomeTaxesEditField = uieditfield(app.UIFigure, 'numeric');
            app.IncomeTaxesEditField.ValueDisplayFormat = '%.2f';
            app.IncomeTaxesEditField.Editable = 'off';
            app.Label_12 = uilabel(app.UIFigure);
            app.Label_12.Text = '$';
            app.NetIncomeEditFieldLabel = uilabel(app.UIFigure);
            app.NetIncomeEditFieldLabel.Text = 'Net Income';
            app.NetIncomeEditField = uieditfield(app.UIFigure, 'numeric');
            app.NetIncomeEditField.ValueDisplayFormat = '%.2f';
            app.NetIncomeEditField.Editable = 'off';
            app.Panel = uipanel(app.UIFigure);
            app.IncomeStatementLabel = uilabel(app.Panel);
            app.IncomeStatementLabel.Text = 'Income Statement';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = incomeWindow(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end