classdef payEmployeeWindow < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        TransactionLabel     matlab.ui.control.Label
        BonusEditField       matlab.ui.control.NumericEditField
        BonusEditFieldLabel  matlab.ui.control.Label
        EmployeesListBox     matlab.ui.control.ListBox
        SelectEmployeeToPayListBoxLabel  matlab.ui.control.Label
        CancelButton         matlab.ui.control.Button
        PayButton            matlab.ui.control.Button
    end

    
    properties (Access = private)
        mainApp
        employees
        update
        idx % The index of current employee clicked
    end
    
    methods (Access = private)
        
        function loadEmployeeList(app)
            [rows, cols] = size(app.employees);
            for i = 1:rows
                list(i) = app.employees(i, 1) + " " + app.employees(i, 2);
            end
            app.EmployeesListBox.Items = list;
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, caller, update)
            app.mainApp = caller;
            app.update = update;
            app.PayButton.Enable = "off";
            app.employees = load('employeesData.mat').data(:, 1:2);
            app.loadEmployeeList();
        end

        % Button pushed function: PayButton
        function PayButtonPushed(app, event)
            app.TransactionLabel.Visible = "on";
            app.TransactionLabel.Text = "Transaction Processing...";
            state = payEmployee(app.idx, app.BonusEditField.Value);
            if state == true
                app.TransactionLabel.Text = "Transaction Complete!";
            else 
                app.TransactionLabel.FontColor = 'r';
                app.TransactionLabel.Text = "Transaction Failed!";
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.mainApp.PayEmployeeButton.Enable = 'on';
            delete(app);  
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            app.mainApp.PayEmployeeButton.Enable = 'on';
            delete(app);  
        end

        % Clicked callback: EmployeesListBox
        function EmployeesListBoxClicked(app, event)
            item = event.InteractionInformation.Item;
            app.idx = item;
            app.PayButton.Enable = "on";
            app.TransactionLabel.Enable = "off";
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.PayButton = uibutton(app.UIFigure, 'push');
            app.PayButton.ButtonPushedFcn = createCallbackFcn(app, @PayButtonPushed, true);
            app.PayButton.Text = 'Pay';
            app.CancelButton = uibutton(app.UIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.SelectEmployeeToPayListBoxLabel = uilabel(app.UIFigure);
            app.SelectEmployeeToPayListBoxLabel.Text = 'Select Employee To Pay';
            app.EmployeesListBox = uilistbox(app.UIFigure);
            app.EmployeesListBox.ClickedFcn = createCallbackFcn(app, @EmployeesListBoxClicked, true);
            app.BonusEditFieldLabel = uilabel(app.UIFigure);
            app.BonusEditFieldLabel.Text = 'Bonus';
            app.BonusEditField = uieditfield(app.UIFigure, 'numeric');
            app.TransactionLabel = uilabel(app.UIFigure);
            app.TransactionLabel.Visible = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = payEmployeeWindow(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end