classdef payrollEventWindow < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        EmployeesListBox       matlab.ui.control.ListBox
        EmployeesListBoxLabel  matlab.ui.control.Label
        RemoveHistoryButton    matlab.ui.control.Button
        PayrollTable           matlab.ui.control.Table
    end

    
    properties (Access = private)
        mainApp
        payEmployeeWindow
        employees
        columnNames
    end
    
    methods (Access = private)
        
        function loadEmployeeList(app)
            [rows, cols] = size(app.employees);
            for i = 1:rows
                list(i) = app.employees(i, 1) + " " + app.employees(i, 2);
            end
            app.EmployeesListBox.Items = list;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, caller, colors)
            app.mainApp = caller;
            app.PayrollTable.BackgroundColor = colors('panelColor');
            app.columnNames = ["Last Name", "Salary", "Bonus", "Federal Tax", "State Tax", "Social Security Tax", "Medicare Tax", "Amount Paid"];
            app.PayrollTable.ColumnName = app.columnNames;
            app.employees = load('employeesData.mat').data(:, 1:2);
            app.loadEmployeeList();
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.mainApp.ViewPayrollEventsButton.Enable = 'on';
            delete(app);  
        end

        % Callback function: not associated with a component
        function PayEmployeeButtonPushed(app, event)
            app.PayEmployeeButton.Enable = 'off';
            
            % Open the options dialog and pass inputs
            app.payEmployeeWindow = payEmployeeWindow(app, true);
        end

        % Button pushed function: RemoveHistoryButton
        function RemoveHistoryButtonPushed(app, event)
            
        end

        % Clicked callback: EmployeesListBox
        function EmployeesListBoxClicked(app, event)
            item = event.InteractionInformation.Item;
            collection = load('payrollEventsData.mat').collection;
            employee = collection(item).data;
            app.PayrollTable.Data = employee;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.PayrollTable = uitable(app.UIFigure);
            app.PayrollTable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.PayrollTable.RowName = {};
            app.PayrollTable.SelectionType = 'row';
            app.RemoveHistoryButton = uibutton(app.UIFigure, 'push');
            app.RemoveHistoryButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveHistoryButtonPushed, true);
            app.RemoveHistoryButton.Enable = 'off';
            app.RemoveHistoryButton.Text = 'Remove History';
            app.EmployeesListBoxLabel = uilabel(app.UIFigure);
            app.EmployeesListBoxLabel.Text = 'Employees';
            app.EmployeesListBox = uilistbox(app.UIFigure);
            app.EmployeesListBox.ClickedFcn = createCallbackFcn(app, @EmployeesListBoxClicked, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = payrollEventWindow(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end