classdef poHistoryWindow < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        RemoveHistoryButton  matlab.ui.control.Button
        HistoryTable         matlab.ui.control.Table
    end

    
    properties (Access = private)
        mainApp
        histories
        columnNames
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, caller, colors)
            app.mainApp = caller;
            app.HistoryTable.BackgroundColor = colors('panelColor');
            app.histories = load("poHistoriesData.mat").data;
            app.columnNames = ["PO Number", "Date", "Supplier", "Part", "Quantity", "Price/Part", "Total"];
            app.HistoryTable.ColumnName = app.columnNames;
            app.HistoryTable.Data = app.histories;
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.mainApp.ViewPOHistoryButton.Enable = 'on';
            delete(app);  
        end

        % Callback function: not associated with a component
        function AddCustomersButtonPushed(app, event)
            app.AddCustomersButton.Enable = 'off';
            
            % Open the options dialog and pass inputs
            app.addCustomerWindow = addCustomerWindow(app, true);
        end

        % Button pushed function: RemoveHistoryButton
        function RemoveHistoryButtonPushed(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.HistoryTable = uitable(app.UIFigure);
            app.HistoryTable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.HistoryTable.RowName = {};
            app.HistoryTable.SelectionType = 'row';
            app.RemoveHistoryButton = uibutton(app.UIFigure, 'push');
            app.RemoveHistoryButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveHistoryButtonPushed, true);
            app.RemoveHistoryButton.Enable = 'off';
            app.RemoveHistoryButton.Text = 'Remove History';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = poHistoryWindow(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end