classdef vendorWindow < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        RemoveVendorsButton  matlab.ui.control.Button
        AddVendorsButton     matlab.ui.control.Button
        VendorTable          matlab.ui.control.Table
    end

    
    properties (Access = private)
        mainApp
        addVendorWindow
        vendors
        columnNames
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, caller, colors)
            app.mainApp = caller;
            app.VendorTable.BackgroundColor = colors('panelColor');
            app.vendors = load("vendorsData.mat").data;
            app.columnNames = ["Company Name", "Part", "Price", "Address Line 1", "Address Line 2", "City", "State", ...
            "Zip Code"];
            app.VendorTable.ColumnName = app.columnNames;
            app.VendorTable.Data = app.vendors;
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.mainApp.ViewVendorsButton.Enable = 'on';
            delete(app);  
        end

        % Button pushed function: AddVendorsButton
        function AddVendorsButtonPushed(app, event)
            app.AddVendorsButton.Enable = 'off';
            
            % Open the options dialog and pass inputs
            app.addVendorWindow = addVendorWindow(app, true);
        end

        % Button pushed function: RemoveVendorsButton
        function RemoveVendorsButtonPushed(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.VendorTable = uitable(app.UIFigure);
            app.VendorTable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.VendorTable.RowName = {};
            app.VendorTable.SelectionType = 'row';
            app.AddVendorsButton = uibutton(app.UIFigure, 'push');
            app.AddVendorsButton.ButtonPushedFcn = createCallbackFcn(app, @AddVendorsButtonPushed, true);
            app.AddVendorsButton.Text = 'Add Vendors';
            app.RemoveVendorsButton = uibutton(app.UIFigure, 'push');
            app.RemoveVendorsButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveVendorsButtonPushed, true);
            app.RemoveVendorsButton.Text = 'Remove Vendors';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = vendorWindow(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end